classdef KrijnApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveGraphButton         matlab.ui.control.Button
        RecallValsButton        matlab.ui.control.Button
        StoreResultsButton      matlab.ui.control.Button
        QWLrangeHigh            matlab.ui.control.NumericEditField
        QWLrangeLow             matlab.ui.control.NumericEditField
        QWLengthnmSlider        matlab.ui.control.Slider
        QWLengthnmSliderLabel   matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        QWLengthnmEditField     matlab.ui.control.NumericEditField
        EditFieldx_2            matlab.ui.control.NumericEditField
        EditFieldy_2            matlab.ui.control.NumericEditField
        ySlider_2               matlab.ui.control.Slider
        ySlider_2Label          matlab.ui.control.Label
        xSlider_2               matlab.ui.control.Slider
        xSlider_2Label          matlab.ui.control.Label
        EditFieldy              matlab.ui.control.NumericEditField
        EditFieldx              matlab.ui.control.NumericEditField
        SubstrateDropDown       matlab.ui.control.DropDown
        SubstrateDropDownLabel  matlab.ui.control.Label
        LayerDropDown           matlab.ui.control.DropDown
        LayerDropDownLabel      matlab.ui.control.Label
        ySlider                 matlab.ui.control.Slider
        ySliderLabel            matlab.ui.control.Label
        xSlider                 matlab.ui.control.Slider
        xSliderLabel            matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LayerDropDown
        function LayerDropDownValueChanged(app, event)
            layerVal = event.Value;
            assignin("base", 'layerComp', layerVal);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            valx = event.Value; % get
            app.EditFieldx.Value = valx; % Set
            assignin("base", 'xLayer', valx);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            valy = event.Value;
            app.EditFieldy.Value = valy;
            assignin("base", 'yLayer', valy);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changed function: EditFieldx
        function EditFieldxValueChanged(app, event)
            valx = app.EditFieldx.Value;
            app.xSlider.Value = valx;
            assignin("base", 'xLayer', valx);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changed function: EditFieldy
        function EditFieldyValueChanged(app, event)
            valy = app.EditFieldy.Value;
            app.ySlider.Value = valy;
            assignin("base", 'yLayer', valy);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changed function: SubstrateDropDown
        function SubstrateDropDownValueChanged(app, event)
            subsVal = event.Value;
            assignin("base", 'subsComp', subsVal);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');            
        end

        % Value changing function: xSlider_2
        function xSlider_2ValueChanging(app, event)
            valx = event.Value; % get
            app.EditFieldx_2.Value = valx; % Set
            assignin("base", 'xSubs', valx);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changing function: ySlider_2
        function ySlider_2ValueChanging(app, event)
            valy = event.Value; % get
            app.EditFieldy_2.Value = valy; % Set
            assignin("base", 'ySubs', valy);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changed function: EditFieldx_2
        function EditFieldx_2ValueChanged(app, event)
            valx = app.EditFieldx_2.Value;
            app.xSlider_2.Value = valx;
            assignin("base", 'xSubs', valx);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changed function: EditFieldy_2
        function EditFieldy_2ValueChanged(app, event)
            valy = app.EditFieldy_2.Value;
            app.ySlider_2.Value = valy;
            assignin("base", 'ySubs', valy);
            evalin("base", 'run dataHandling.m');
            evalin("base", 'run button.m');
        end

        % Value changed function: QWLengthnmEditField
        function QWLengthnmEditFieldValueChanged(app, event)
            QWL = app.QWLengthnmEditField.Value;
            app.QWLengthnmSlider.Value = QWL;
            assignin("base", 'QWLength', QWL);
            evalin("base", 'run groundState.m');
        end

        % Value changing function: QWLengthnmSlider
        function QWLengthnmSliderValueChanging(app, event)
            QWL = event.Value;
            app.QWLengthnmEditField.Value = QWL;
            assignin("base", 'QWLength', QWL);
            evalin("base", 'run groundState.m');
        end

        % Value changed function: QWLrangeHigh
        function QWLrangeHighValueChanged(app, event)
            high = app.QWLrangeHigh.Value;
            low = app.QWLrangeLow.Value;
            app.QWLengthnmSlider.Limits = [low high];
        end

        % Value changed function: QWLrangeLow
        function QWLrangeLowValueChanged(app, event)
            high = app.QWLrangeHigh.Value;
            low = app.QWLrangeLow.Value;
            app.QWLengthnmSlider.Limits = [low high];
        end

        % Button pushed function: StoreResultsButton
        function StoreResultsButtonPushed(app, event)
            evalin("base", 'run store.m');
        end

        % Button pushed function: RecallValsButton
        function RecallValsButtonPushed(app, event)
            evalin("base", 'run recall.m');
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            evalin("base", 'run saveGraphics.m');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'QW diagram')
            xlabel(app.UIAxes, 'Length (nm)')
            ylabel(app.UIAxes, 'E (eV)')
            zlabel(app.UIAxes, 'Z')
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [0 1];
            app.xSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.MinorTicks = [0 0.05 1];
            app.xSlider.Step = 0.01;
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [0 1];
            app.ySlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.MinorTicks = [0 0.05 1];
            app.ySlider.Step = 0.01;
            app.LayerDropDownLabel = uilabel(app.UIFigure);
            app.LayerDropDownLabel.Text = 'Layer';
            app.LayerDropDown = uidropdown(app.UIFigure);
            app.LayerDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerDropDownValueChanged, true);
            app.SubstrateDropDownLabel = uilabel(app.UIFigure);
            app.SubstrateDropDownLabel.Text = 'Substrate';
            app.SubstrateDropDown = uidropdown(app.UIFigure);
            app.SubstrateDropDown.ValueChangedFcn = createCallbackFcn(app, @SubstrateDropDownValueChanged, true);
            app.EditFieldx = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldx.ValueChangedFcn = createCallbackFcn(app, @EditFieldxValueChanged, true);
            app.EditFieldy = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldy.ValueChangedFcn = createCallbackFcn(app, @EditFieldyValueChanged, true);
            app.xSlider_2Label = uilabel(app.UIFigure);
            app.xSlider_2Label.Text = 'x';
            app.xSlider_2 = uislider(app.UIFigure);
            app.xSlider_2.Limits = [0 1];
            app.xSlider_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.xSlider_2.ValueChangingFcn = createCallbackFcn(app, @xSlider_2ValueChanging, true);
            app.xSlider_2.MinorTicks = [0 0.05 1];
            app.xSlider_2.Step = 0.01;
            app.ySlider_2Label = uilabel(app.UIFigure);
            app.ySlider_2Label.Text = 'y';
            app.ySlider_2 = uislider(app.UIFigure);
            app.ySlider_2.Limits = [0 1];
            app.ySlider_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ySlider_2.ValueChangingFcn = createCallbackFcn(app, @ySlider_2ValueChanging, true);
            app.ySlider_2.MinorTicks = [0 0.05 1];
            app.ySlider_2.Step = 0.1;
            app.EditFieldy_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldy_2.ValueChangedFcn = createCallbackFcn(app, @EditFieldy_2ValueChanged, true);
            app.EditFieldx_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldx_2.ValueChangedFcn = createCallbackFcn(app, @EditFieldx_2ValueChanged, true);
            app.QWLengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.QWLengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @QWLengthnmEditFieldValueChanged, true);
            app.QWLengthnmEditField.Value = 8;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.QWLengthnmSliderLabel = uilabel(app.UIFigure);
            app.QWLengthnmSliderLabel.Text = 'QW Length (nm)';
            app.QWLengthnmSlider = uislider(app.UIFigure);
            app.QWLengthnmSlider.Limits = [2 16];
            app.QWLengthnmSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.QWLengthnmSlider.ValueChangingFcn = createCallbackFcn(app, @QWLengthnmSliderValueChanging, true);
            app.QWLengthnmSlider.MinorTicks = [0 0.05 1];
            app.QWLengthnmSlider.Step = 0.1;
            app.QWLengthnmSlider.Value = 8;
            app.QWLrangeLow = uieditfield(app.UIFigure, 'numeric');
            app.QWLrangeLow.ValueChangedFcn = createCallbackFcn(app, @QWLrangeLowValueChanged, true);
            app.QWLrangeLow.Value = 2;
            app.QWLrangeHigh = uieditfield(app.UIFigure, 'numeric');
            app.QWLrangeHigh.ValueChangedFcn = createCallbackFcn(app, @QWLrangeHighValueChanged, true);
            app.QWLrangeHigh.Value = 16;
            app.StoreResultsButton = uibutton(app.UIFigure, 'push');
            app.StoreResultsButton.ButtonPushedFcn = createCallbackFcn(app, @StoreResultsButtonPushed, true);
            app.StoreResultsButton.Text = 'Store Results';
            app.RecallValsButton = uibutton(app.UIFigure, 'push');
            app.RecallValsButton.ButtonPushedFcn = createCallbackFcn(app, @RecallValsButtonPushed, true);
            app.RecallValsButton.Text = 'Recall Vals';
            app.SaveGraphButton = uibutton(app.UIFigure, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KrijnApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end